classdef Plan_Calculate < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        TypeTab                       matlab.ui.container.Tab
        QuestiontypeLabel             matlab.ui.control.Label
        PlanStressCheckBox            matlab.ui.control.CheckBox
        PlanStrainCheckBox            matlab.ui.control.CheckBox
        ElementtypeLabel              matlab.ui.control.Label
        TriangularCheckBox            matlab.ui.control.CheckBox
        QuadrilateralCheckBox         matlab.ui.control.CheckBox
        PlaneElementCalculationLabel  matlab.ui.control.Label
        MixedCheckBox                 matlab.ui.control.CheckBox
        MaterialTab                   matlab.ui.container.Tab
        coordinate2                   matlab.ui.control.Table
        Add2Button                    matlab.ui.control.Button
        Delete2                       matlab.ui.control.Button
        ElasticmodulusLabel           matlab.ui.control.Label
        Elasticmodulus                matlab.ui.control.TextArea
        PoissonratioLabel             matlab.ui.control.Label
        Poissonratio                  matlab.ui.control.TextArea
        ThicknessLabel                matlab.ui.control.Label
        Thickness                     matlab.ui.control.TextArea
        CoordinateTab                 matlab.ui.container.Tab
        coordinate1                   matlab.ui.control.Table
        Add1Button                    matlab.ui.control.Button
        XcoordinateLabel              matlab.ui.control.Label
        Xcoordinate                   matlab.ui.control.TextArea
        YcoordinateLabel              matlab.ui.control.Label
        Ycoordinate                   matlab.ui.control.TextArea
        Plot1                         matlab.ui.control.Button
        Delete                        matlab.ui.control.Button
        ScaleLabel                    matlab.ui.control.Label
        scale1                        matlab.ui.control.TextArea
        ElementnodesTab               matlab.ui.container.Tab
        Elementnodes                  matlab.ui.control.Table
        Add3Button                    matlab.ui.control.Button
        Plot2                         matlab.ui.control.Button
        Delete3                       matlab.ui.control.Button
        MaterialLabel                 matlab.ui.control.Label
        elementmaterial               matlab.ui.control.TextArea
        NodesLabel                    matlab.ui.control.Label
        elementnodes                  matlab.ui.control.TextArea
        ScaleLabel_2                  matlab.ui.control.Label
        scale2                        matlab.ui.control.TextArea
        TypeLabel                     matlab.ui.control.Label
        elementtype2                  matlab.ui.control.TextArea
        ConstraintsTab                matlab.ui.container.Tab
        constriant                    matlab.ui.control.Table
        Add4Button                    matlab.ui.control.Button
        Plot3                         matlab.ui.control.Button
        Delete4                       matlab.ui.control.Button
        PositionLabel                 matlab.ui.control.Label
        consposition                  matlab.ui.control.TextArea
        ScaleLabel_3                  matlab.ui.control.Label
        scale3                        matlab.ui.control.TextArea
        LoadsTab                      matlab.ui.container.Tab
        load1                         matlab.ui.control.Table
        Add5                          matlab.ui.control.Button
        Plot4                         matlab.ui.control.Button
        Delete5                       matlab.ui.control.Button
        PositionLabel_2               matlab.ui.control.Label
        loadposition                  matlab.ui.control.TextArea
        ValueLabel                    matlab.ui.control.Label
        value1                        matlab.ui.control.TextArea
        ScaleLabel_4                  matlab.ui.control.Label
        scale4                        matlab.ui.control.TextArea
        CalculateTab                  matlab.ui.container.Tab
        CalculateButton               matlab.ui.control.Button
        Type2                         matlab.ui.control.TextArea
        ElementtypeLabel_2            matlab.ui.control.Label
        Type1                         matlab.ui.control.TextArea
        QuestiontypeLabel_2           matlab.ui.control.Label
        nnode                         matlab.ui.control.NumericEditField
        NumberofnodesLabel            matlab.ui.control.Label
        NumberofmaterialsLabel_2      matlab.ui.control.Label
        nmaterial                     matlab.ui.control.NumericEditField
        NumberofelementsLabel         matlab.ui.control.Label
        nelement                      matlab.ui.control.NumericEditField
        NumberofconstraintsLabel_2    matlab.ui.control.Label
        nconstraint                   matlab.ui.control.NumericEditField
        NumberofloadsLabel_2          matlab.ui.control.Label
        nload                         matlab.ui.control.NumericEditField
        SaveButton                    matlab.ui.control.Button
        OpenButton                    matlab.ui.control.Button
        DisplacementTab               matlab.ui.container.Tab
        Structure2                    matlab.ui.control.UIAxes
        DisplacementLabel             matlab.ui.control.Label
        Plot5                         matlab.ui.control.Button
        ScaleLabel_5                  matlab.ui.control.Label
        scale5                        matlab.ui.control.TextArea
        Structure                     matlab.ui.control.UIAxes
        StructureLabel                matlab.ui.control.Label
    end

    methods (Access = private)

        % Cell edit callback: coordinate1
        function Statement(app, event)
        %------------------------------------------------------------------
        % Plane Calculation.mlapp
        %
        % The main program for calculating plane problems, including triangular element, 
        % four node isoparametric element and hybrid element
        % 
        % This program is only for learning and communication. 
        % Please do not use it for business without permission. 
        % Please indicate the source for reprint
        %
        % Written by Jia Xu & Ruoxi Liu - 04/13/2022
        % Contact: liuroci@163.com
        %------------------------------------------------------------------
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
        global displacement
                    n_node=app.nnode.Value;
                    n_element=app.nelement.Value;
                    n_material=app.nmaterial.Value;
                    n_constraint=app.nconstraint.Value;
                    n_load=app.nload.Value;
                    type=str2num(cell2mat(app.Type1.Value));
                    element_type=app.Elementnodes.Data(:,3);
                    x=app.coordinate1.Data(:,2);
                    y=app.coordinate1.Data(:,3);
                    element_material=app.Elementnodes.Data(:,2);
                    element_type1=str2num(cell2mat(app.Type2.Value));
                    if element_type1==3
                        Nnode=3;
                    else
                        Nnode=4;
                    end
                    
                    element_nodes=app.Elementnodes.Data(:,4:3+Nnode);
                    material=app.coordinate2.Data(:,2:4);
                    constraint1=app.constriant.Data(:,2);
                    load=app.load1.Data(:,2:3);
                    [displacement,stress,stress1]=Tri_Quad(n_node,n_element,type,element_type,x,y,element_material,element_nodes,material,constraint1,load);
                    
                    x_displacement=displacement(1:2:2*n_node-1);
                    y_displacement=displacement(2:2:2*n_node);
                   
                    file=fopen('Output_Data.txt','wt');
                    fprintf(file,'===================Input Data=======================\n');
                    if element_type==1
                    fprintf(file,'The type of elements:Triangular\n');
                    elseif element_type==2
                    fprintf(file,'The Number of Nodes:Rectangle\n');
                    elseif element_type==3
                    fprintf(file,'The Number of Nodes:Isoparametric Fournode\n');        
                    end
                    
                    fprintf(file,'The Number of Nodes:%3d\n',n_node);
                    fprintf(file,'The Number of Elements:%3d\n',n_element);
                    fprintf(file,'The Number of Type of Material Parameters:%3d\n',size(material,1));
                    fprintf(file,'The Number of Restrictions:%3d\n',size(constraint1,2));
                    fprintf(file,'The Number of Nodal Loads:%3d\n',size(load,1));
                    fprintf(file,'Problem Type:  ');
                    if type==0;fprintf(file,'Plane Stress Problem\n\n');else;fprintf(file,'Plane Strain Problem\n\n');end
                    fprintf(file,'Coordinates x and y of Nodes:\n');
                    fprintf(file,'         Node         x            y\n');
                    for i=1:n_node
                    fprintf(file,'%12d%13.2f%13.2f\n',i,x(i),y(i));
                    end
                    fprintf(file,'\nThe Node Numbers of Elements:\n');
                    fprintf(file,'         Element      Type              Nodes\n');
                    if element_type==1
                    for i=1:n_element
                    fprintf(file,'%12d%13d%12d%6d%6d\n',i,element_material(i),element_nodes(i,1),element_nodes(i,2),element_nodes(i,3));
                    end
                    else
                    element_type=str2num(cell2mat(app.Type2.Value));
                    if element_type==3
                        Nnode=3;
                    else
                        Nnode=4;
                    end
                    if Nnode==3
                    for i=1:n_element
                    fprintf(file,'%12d%13d%12d%6d%6d%6d\n',i,element_material(i),element_nodes(i,1),element_nodes(i,2),element_nodes(i,3));
                    end
                    else
                    for i=1:n_element
                    fprintf(file,'%12d%13d%12d%6d%6d%6d\n',i,element_material(i),element_nodes(i,1),element_nodes(i,2),element_nodes(i,3),element_nodes(i,4));
                    end 
                    end
                    
                    end
                    fprintf(file,'\nMaterial Parameters:\n');
                    fprintf(file,'         Type      Elastic Modulus    Poisson Ratio   Thickness\n');
                    for i=1:size(material,1)
                    fprintf(file,'%12d%15.3g%20.3g%11.3g\n',i,material(i,1),material(i,2),material(i,3));
                    end
                    fprintf(file,'\n\nThe Position of Restriction:');
                    for i=1:size(constraint1,2)
                    fprintf(file,'%5d',constraint1(i));
                    end
                    fprintf(file,'\n\nNodal Loads:\n');
                    fprintf(file,'         Position        Value\n');
                    for i=1:size(load,1)
                    fprintf(file,'%14d%16.3g\n',load(i,1),load(i,2));
                    end
                    fprintf(file,'\n\n===================Output Data=======================\n');
                    fprintf(file,'Displacements of Nodes :\n');
                    fprintf(file,'         Node        x            y\n');
                    for i=1:n_node
                    fprintf(file,'%12d%13.4g%13.4g\n',i,x_displacement(i),y_displacement(i));
                    end
                    fprintf(file,'\nStresses of Elements :\n');
                    fprintf(file,'         Element      stress_x      stress_y      stress_t         S1            S2          Ceta\n');
                    for i=1:n_element
                    fprintf(file,'%12d%17.4g%14.4g%14.4g%14.4g%14.4g%14.4g\n',i,stress(i,1),stress(i,2),stress(i,3),stress1(i,1),stress1(i,2),stress1(i,3));
                    end
                    fclose(file);
                                        

        end

        % Button pushed function: Add1Button
        function Add1ButtonPushed(app, event)

            coordinate=app.coordinate1.Data;
            if isempty(coordinate)==1
                x=[];y=[];
            else
               x=coordinate(:,2);
               y=coordinate(:,3);
            end
            x1=str2num(cell2mat(app.Xcoordinate.Value));
            y1=str2num(cell2mat(app.Ycoordinate.Value));
            x=[x;x1];y=[y;y1];
            nnnode=size(x,1);
            coordinate11=zeros(nnnode,3);
            coordinate11(:,1)=1:nnnode;
            coordinate11(:,2)=x;
            coordinate11(:,3)=y;
            app.coordinate1.Data=coordinate11;
            
            n_node=size(coordinate11,1);
            app.nnode.Value=n_node;  
            
        end

        % Value changed function: PlanStressCheckBox
        function PlanStressCheckBoxValueChanged(app, event)
            value = app.PlanStressCheckBox.Value;
            %check
            if value==true
                app.Type1.Value='0';
                app.PlanStrainCheckBox.Value=false;
            end
            if value==false
               app.Type1.Value=' ';
            end
        end

        % Callback function
        function Type1ValueChanged(app, event)
          
        end

        % Value changed function: PlanStrainCheckBox
        function PlanStrainCheckBoxValueChanged(app, event)
            value = app.PlanStrainCheckBox.Value;
            if value==true
                app.Type1.Value='1';
                app.PlanStressCheckBox.Value=false;
            end
            if value==false
               app.Type1.Value=' ';
            end
        end

        % Value changed function: Type2
        function Type2ValueChanged(app, event)
           
        end

        % Value changed function: TriangularCheckBox
        function TriangularCheckBoxValueChanged(app, event)
            value = app.TriangularCheckBox.Value;
            if value==true
                app.Type2.Value='3';
                app.QuadrilateralCheckBox.Value=false;
                app.MixedCheckBox.Value=false;
                app.elementtype2.Value='3';
            end
            if value==false
               app.Type2.Value=' ';
            end
        end

        % Value changed function: QuadrilateralCheckBox
        function QuadrilateralCheckBoxValueChanged(app, event)
            value = app.QuadrilateralCheckBox.Value;
            if value==true
                app.Type2.Value='4';
                app.TriangularCheckBox.Value=false;
                app.MixedCheckBox.Value=false;
                app.elementtype2.Value='4';
            end
            if value==false
               app.Type2.Value=' ';
            end
        end

        % Callback function
        function nnodeValueChanged(app, event)

        end

        % Button pushed function: Plot1
        function Plot1ButtonPushed(app, event)

            scale=str2num(cell2mat(app.scale1.Value));
            if isempty(scale)==1
                 scale=1;
            end

            coordinate=app.coordinate1.Data;
            if isempty(coordinate)==1
                return
            else
            x=coordinate(:,2);
            y=coordinate(:,3);
            n_node=size(x,1);
            s=scatter(app.Structure,x,y,'filled','M');
            s.LineWidth = 2;
            s.MarkerEdgeColor = 'b';
            s.MarkerFaceColor = [0 0 1];
            for i=1:n_node
                text(app.Structure,x(i)-0.005*scale,y(i)-0.005*scale,num2str(i),'fontsize',30*scale,'color','b','VerticalAlignment','bottom') 
            end
            axis(app.Structure,[min(x)-scale max(x)+scale min(y)-scale max(y)+scale]);
            end
        end

        % Button pushed function: Delete
        function DeleteButtonPushed(app, event)
            
            coordinate=app.coordinate1.Data;
            if isempty(coordinate)==1
                coordinate11=[];
            else
                nnnnode=size(coordinate,1);
                nnnode=nnnnode-1;
                coordinate11=zeros(nnnode,3);
                coordinate11(:,1)=1:nnnode;
                coordinate11(:,2)=coordinate(1:nnnode,2);
                coordinate11(:,3)=coordinate(1:nnnode,3);
            end
                app.coordinate1.Data=coordinate11;
              
            n_node=size(coordinate11,1);
            app.nnode.Value=n_node;  
            
        end

        % Button pushed function: Add2Button
        function Add2ButtonPushed(app, event)
            
            material=app.coordinate2.Data;
            if isempty(material)==1
                E=[];u=[];t=[];
            else
               E=material(:,2);
               u=material(:,3);
               t=material(:,4);
            end
            E1=str2num(cell2mat(app.Elasticmodulus.Value));
            u1=str2num(cell2mat(app.Poissonratio.Value));
            t1=str2num(cell2mat(app.Thickness.Value));
            
            E=[E;E1];u=[u;u1];t=[t;t1];
            nnmaterial=size(E,1);
            material1=zeros(nnmaterial,4);
            material1(:,1)=1:nnmaterial;
            material1(:,2)=E;
            material1(:,3)=u;
            material1(:,4)=t;
            app.coordinate2.Data=material1;
            n_material=size(material1,1);
            app.nmaterial.Value=n_material;  
            
            
        end

        % Button pushed function: Delete2
        function Delete2ButtonPushed(app, event)
            
            coordinate=app.coordinate2.Data;
            if isempty(coordinate)==1
                coordinate11=[];
            else
                nnnnode=size(coordinate,1);
                nnnode=nnnnode-1;
                coordinate11=zeros(nnnode,3);
                coordinate11(:,1)=1:nnnode;
                coordinate11(:,2)=coordinate(1:nnnode,2);
                coordinate11(:,3)=coordinate(1:nnnode,3);
                coordinate11(:,4)=coordinate(1:nnnode,4);
            end
                app.coordinate2.Data=coordinate11;
                n_material=size(coordinate11,1);
                app.nmaterial.Value=n_material;  
                
        end

        % Button pushed function: Add3Button
        function Add3ButtonPushed(app, event)
              
            elenodes=app.Elementnodes.Data;
            eletype=str2num(cell2mat(app.elementtype2.Value));
            enode=eletype+3;
            
            mate1=str2num(cell2mat(app.elementmaterial.Value));
            nodes1=str2num(cell2mat(app.elementnodes.Value));
           
            num=size(elenodes,1);
            elenodesnew=zeros(1,7);
            elenodesnew(1:(enode))=[num+1,mate1,eletype,nodes1];
            if size(elenodes,2)<7
                elenodes(:,7)=zeros;
            end
            
            elenodes1=[elenodes;elenodesnew];
            app.Elementnodes.Data=elenodes1;
            
            n_element=size(elenodes1,1);
            app.nelement.Value=n_element;  
            
        end

        % Button pushed function: Delete3
        function Delete3ButtonPushed(app, event)
                coordinate=app.Elementnodes.Data;
            if isempty(coordinate)==1
                coordinate=[];
                app.Elementnodes.Data=coordinate;
            else
                nnnnode=size(coordinate,1);
                coordinate(nnnnode,:)=[];
                app.Elementnodes.Data=coordinate;
            end
            
            n_element=size(coordinate,1);
            app.nelement.Value=n_element;  
        end

        % Button pushed function: Plot2
        function Plot2ButtonPushed(app, event)
            
            
            scale=str2num(cell2mat(app.scale2.Value));
            
            if isempty(scale)==1
                 scale=1;
            end
            coordinate=app.coordinate1.Data;
            if isempty(coordinate)==1
                return
            else
            x=coordinate(:,2);
            y=coordinate(:,3);
            n_node=size(x,1);
            s=scatter(app.Structure,x,y,'filled','M');
            s.LineWidth = 2;
            s.MarkerEdgeColor = 'b';
            s.MarkerFaceColor = [0 0 1];
            hold(app.Structure, 'on')
            for i=1:n_node
                text(app.Structure,x(i)-0.005*scale,y(i)-0.005*scale,num2str(i),'fontsize',30*scale,'color','b','VerticalAlignment','bottom') 
            end
             element_type=str2num(cell2mat(app.Type2.Value));
             if element_type==3
                    Nnode=3;
             else
                    Nnode=4;
             end
            
            element_nodes=app.Elementnodes.Data;
            n_elementnode1=element_nodes(:,3);
            n_element=size(element_nodes,1);
            element_nodes=element_nodes(:,4:3+Nnode);
            if isempty(element_nodes)==1
                return
            else
            for i=1:n_element
                n_elementnode=n_elementnode1(i);
                for j=1:n_elementnode
                    jj=mod(j,n_elementnode)+1;
                    xx=[x(element_nodes(i,j)),x(element_nodes(i,jj))];
                    yy=[y(element_nodes(i,j)),y(element_nodes(i,jj))];
                    plot(app.Structure,xx,yy,'b','Marker','.','LineStyle','-','LineWidth',2);
                end
            end
            
            
            for i=1:n_element
                sumx=0;sumy=0;
                n_elementnode=n_elementnode1(i);
                for j=1:n_elementnode
                    sumx=sumx+x(element_nodes(i,j));
                    sumy=sumy+y(element_nodes(i,j));
                end
                    xx=(sumx)/n_elementnode;
                    yy=(sumy)/n_elementnode;
                    plot(app.Structure,xx,yy,'o','Markersize',20*scale,'color','m')
                    text(app.Structure,xx-0.05*scale,yy,num2str(i),'fontsize',20*scale,'color','m') 
            end
            
            hold(app.Structure, 'off')
            axis(app.Structure,[min(x)-scale max(x)+scale min(y)-scale max(y)+scale]);
            end
            end
        end

        % Button pushed function: Add4Button
        function Add4ButtonPushed(app, event)
              
            constriant1=app.constriant.Data;
            cons1=str2num(cell2mat(app.consposition.Value));
      
            if isempty(constriant1)==1
                cons=[];
            else
                cons=constriant1(:,2);
            end
            
            cons=[cons;cons1];
            nncons=size(cons,1);
            cons11(:,1)=1:nncons;
            cons11(:,2)=cons;
            app.constriant.Data=cons11;
            
            n_const=size(cons11,1);
            app.nconstraint.Value=n_const;  
            
            
        end

        % Button pushed function: Delete4
        function Delete4ButtonPushed(app, event)
            
               coordinate=app.constriant.Data;
            if isempty(coordinate)==1
                coordinate=[];
                app.constriant.Data=coordinate;
            else
                nnnnode=size(coordinate,1);
                coordinate(nnnnode,:)=[];
                app.constriant.Data=coordinate;
            end
            
            n_const=size(coordinate,1);
            app.nconstraint.Value=n_const;  
            
        end

        % Button pushed function: Plot3
        function Plot3ButtonPushed(app, event)
            
            scale=str2num(cell2mat(app.scale3.Value));
            
            if isempty(scale)==1
                 scale=1;
            end
            coordinate=app.coordinate1.Data;
            if isempty(coordinate)==1
                return
            else
            x=coordinate(:,2);
            y=coordinate(:,3);
            n_node=size(x,1);
            s=scatter(app.Structure,x,y,'filled','M');
            s.LineWidth = 2;
            s.MarkerEdgeColor = 'b';
            s.MarkerFaceColor = [0 0 1];
            hold(app.Structure, 'on')
            for i=1:n_node
                text(app.Structure,x(i)-0.005*scale,y(i)-0.005*scale,num2str(i),'fontsize',30*scale,'color','b','VerticalAlignment','bottom') 
            end
             element_type=str2num(cell2mat(app.Type2.Value));
             if element_type==3
                    Nnode=3;
             else
                    Nnode=4;
             end
            
            element_nodes=app.Elementnodes.Data;
            n_elementnode1=element_nodes(:,3);
            n_element=size(element_nodes,1);
            element_nodes=element_nodes(:,4:3+Nnode);
            if isempty(element_nodes)==1
                return
            else
            for i=1:n_element
                n_elementnode=n_elementnode1(i);
                for j=1:n_elementnode
                    jj=mod(j,n_elementnode)+1;
                    xx=[x(element_nodes(i,j)),x(element_nodes(i,jj))];
                    yy=[y(element_nodes(i,j)),y(element_nodes(i,jj))];
                    plot(app.Structure,xx,yy,'b','Marker','.','LineStyle','-','LineWidth',2);
                end
            end
            
            
            for i=1:n_element
                sumx=0;sumy=0;
                n_elementnode=n_elementnode1(i);
                for j=1:n_elementnode
                    sumx=sumx+x(element_nodes(i,j));
                    sumy=sumy+y(element_nodes(i,j));
                end
                    xx=(sumx)/n_elementnode;
                    yy=(sumy)/n_elementnode;
                    plot(app.Structure,xx,yy,'o','Markersize',20*scale,'color','m')
                    text(app.Structure,xx-0.05*scale,yy,num2str(i),'fontsize',20*scale,'color','m') 
            end
            
            
            constrain=app.constriant.Data;
            constrain=constrain(:,2);
            if isempty(constrain)==1
                return
            else
            for i=1:size(constrain,1)
                if  mod(constrain(i),2)==0
                    px=0;py=1;po=constrain(i)/2;
                else
                    px=1;py=0;po=(constrain(i)+1)/2;
                end
                xx=[x(po)-px*0.3*scale,x(po)];
                yy=[y(po)-py*0.3*scale,y(po)];
                plot(app.Structure,xx,yy,'r','Marker','o','LineStyle','-','LineWidth',2);
            end
            
            
            hold(app.Structure, 'off')
            axis(app.Structure,[min(x)-scale max(x)+scale min(y)-scale max(y)+scale]);
            end
            end
            end
        end

        % Button pushed function: Add5
        function Add5ButtonPushed(app, event)
            
            loads=app.load1.Data;
     
            if isempty(loads)==1
                loadposition1=[];loadvalue=[];
            else
               loadposition1=loads(:,2);
               loadvalue=loads(:,3);
            end
            loadposition11=str2num(cell2mat(app.loadposition.Value));
            loadvalue1=str2num(cell2mat(app.value1.Value));
            loadposition1=[loadposition1;loadposition11];loadvalue=[loadvalue;loadvalue1];
            nload1=size(loadposition1,1);
            loads11(:,1)=1:nload1;
            loads11(:,2)=loadposition1;
            loads11(:,3)=loadvalue;
            app.load1.Data=loads11;
            
            n_load=size(loads11,1);
            app.nload.Value=n_load;  
            
        end

        % Cell edit callback: coordinate2
        function coordinate2CellEdit(app, event)
            
        end

        % Button pushed function: Delete5
        function Delete5ButtonPushed(app, event)
                coordinate=app.load1.Data;
            if isempty(coordinate)==1
                coordinate=[];
                app.load1.Data=coordinate;
            else
                nnnnode=size(coordinate,1);
                coordinate(nnnnode,:)=[];
                app.load1.Data=coordinate;
            end
            
            n_load=size(coordinate,1);
            app.nload.Value=n_load;  
            
        end

        % Button pushed function: Plot4
        function Plot4ButtonPushed(app, event)
            
            scale=str2num(cell2mat(app.scale4.Value));
            
            if isempty(scale)==1
                 scale=1;
            end
            coordinate=app.coordinate1.Data;
            if isempty(coordinate)==1
                return
            else
            x=coordinate(:,2);
            y=coordinate(:,3);
            n_node=size(x,1);
            s=scatter(app.Structure,x,y,'filled','M');
            s.LineWidth = 2;
            s.MarkerEdgeColor = 'b';
            s.MarkerFaceColor = [0 0 1];
            hold(app.Structure, 'on')
            for i=1:n_node
                text(app.Structure,x(i)-0.005*scale,y(i)-0.005*scale,num2str(i),'fontsize',30*scale,'color','b','VerticalAlignment','bottom') 
            end
            
                 element_type=str2num(cell2mat(app.Type2.Value));
                    
                    
                    if element_type==3
                        Nnode=3;
                    else
                        Nnode=4;
                    end


            element_nodes=app.Elementnodes.Data;
            n_elementnode1=element_nodes(:,3);
            n_element=size(element_nodes,1);
            element_nodes=element_nodes(:,4:3+Nnode);
            if isempty(element_nodes)==1
                return
            else
            for i=1:n_element
                n_elementnode=n_elementnode1(i);
                for j=1:n_elementnode
                    jj=mod(j,n_elementnode)+1;
                    xx=[x(element_nodes(i,j)),x(element_nodes(i,jj))];
                    yy=[y(element_nodes(i,j)),y(element_nodes(i,jj))];
                    plot(app.Structure,xx,yy,'b','Marker','.','LineStyle','-','LineWidth',2);
                end
            end
            
            
            for i=1:n_element
                sumx=0;sumy=0;
                n_elementnode=n_elementnode1(i);
                for j=1:n_elementnode
                    sumx=sumx+x(element_nodes(i,j));
                    sumy=sumy+y(element_nodes(i,j));
                end
                    xx=(sumx)/n_elementnode;
                    yy=(sumy)/n_elementnode;
                    plot(app.Structure,xx,yy,'o','Markersize',20*scale,'color','m')
                    text(app.Structure,xx-0.05*scale,yy,num2str(i),'fontsize',20*scale,'color','m') 
            end
            
            
            constrain=app.constriant.Data;
            constrain=constrain(:,2);
            if isempty(constrain)==1
                return
            else
            for i=1:size(constrain,1)
                if  mod(constrain(i),2)==0
                    px=0;py=1;po=constrain(i)/2;
                else
                    px=1;py=0;po=(constrain(i)+1)/2;
                end
                xx=[x(po)-px*0.3*scale,x(po)];
                yy=[y(po)-py*0.3*scale,y(po)];
                plot(app.Structure,xx,yy,'r','Marker','o','LineStyle','-','LineWidth',2);
            end
            
            
            load=app.load1.Data;
            load=load(:,2:3);
            if isempty(load)==1
                return
            else
            for i=1:size(load,1)    
                dir=load(i,2)/abs(load(i,2));
                 if  mod(load(i,1),2)==0
                    px=0;py=1;po=load(i,1)/2;
                    if dir<0
                       quiver(app.Structure,x(po),y(po)+0.5*py*scale,px*dir,py*dir,'r','AutoScaleFactor',0.5*scale,'LineWidth',2,'MaxHeadSize',scale)
                    else
                       quiver(app.Structure,x(po),y(po),px*dir,py*dir,'r','AutoScaleFactor',0.5*scale,'LineWidth',2,'MaxHeadSize',scale) 
                    end
                       text(app.Structure,x(po)-px*0.4*scale,y(po)+(0.07+py*0.4)*scale,num2str(load(i,2)),'fontsize',20*scale,'color','r','VerticalAlignment','bottom')
                 else
                    px=1;py=0;po=(load(i,1)+1)/2;
                    if dir<0
                       quiver(app.Structure,x(po),y(po)+0.5*py*scale,px*dir,py*dir,'r','AutoScaleFactor',0.5*scale,'LineWidth',2,'MaxHeadSize',scale)
                    else
                       quiver(app.Structure,x(po)-0.5*px*scale,y(po)+0.5*py*scale,px*dir,py*dir,'r','AutoScaleFactor',0.5*scale,'LineWidth',2,'MaxHeadSize',scale) 
                    end
                       text(app.Structure,x(po)-(0.1+px*0.4)*scale,y(po)+py*0.4*scale,num2str(load(i,2)),'fontsize',20*scale,'color','r','VerticalAlignment','bottom')
                 end
            end
            
            
            hold(app.Structure, 'off')
            axis(app.Structure,[min(x)-scale max(x)+scale min(y)-scale max(y)+scale]);
            end
            end
            end
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
                  
                    n_node=app.nnode.Value;
                    n_element=app.nelement.Value;
                    n_material=app.nmaterial.Value;
                    n_constraint=app.nconstraint.Value;
                    n_load=app.nload.Value;
                    type=str2num(cell2mat(app.Type1.Value));
                    element_type1=str2num(cell2mat(app.Type2.Value));
                    x=app.coordinate1.Data(:,2);
                    y=app.coordinate1.Data(:,3);
                    element_material=app.Elementnodes.Data(:,2);
                    element_type=app.Elementnodes.Data(:,3);
                    element_type11=str2num(cell2mat(app.Type2.Value));
                    if element_type11==3
                        Nnode=3;
                    else
                        Nnode=4;
                    end
                  
                    element_nodes=app.Elementnodes.Data(:,4:3+Nnode);
                    material=app.coordinate2.Data(:,2:4);
                    constraint1=app.constriant.Data(:,2);
                    load=app.load1.Data(:,2:3);
  
%                     if element_type==3
%                         Nnode=3;
%                     else
%                         Nnode=4;
%                     end
                    
                    file=fopen('Input_Data.txt','wt');
                    fprintf(file,'%6d',n_node);
                    fprintf(file,'%6d',n_element);
                    fprintf(file,'%6d',n_material);
                    fprintf(file,'%6d',n_constraint);
                    fprintf(file,'%6d\n',n_load);
                    fprintf(file,'%6d',type);
                    fprintf(file,'%6d\n',element_type1);
                
                    for i=1:n_node
                        fprintf(file,'%6d%8.3f%8.3f\n',i,x(i),y(i));
                    end
                    
                  
                    for i=1:n_material
                        fprintf(file,'%6d%8.3g%8.3g%8.3g\n',i,material(i,1),material(i,2),material(i,3));
                    end
                    
                    for i=1:n_element
                        fprintf(file,'%6d%6d',i,element_material(i));
                        fprintf(file,'%6d',element_type(i));
                        for j=1:element_type(i)
                             fprintf(file,'%6d',element_nodes(i,j));
                        end
                        fprintf(file,'\n');
                    end
                    for i=1:n_constraint
                         fprintf(file,'%6d%6d\n',i,constraint1(i));
                    end
                    
                    for i=1:n_load
                        fprintf(file,'%6d%6d%8.3g\n',i,load(i,1),load(i,2));
                    end
                    fclose(file);
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
                    file=('Input_Data.txt');
                    Indata=dlmread(file);
                    n_node=Indata(1,1);
                    n_element=Indata(1,2);
                    n_material=Indata(1,3);
                    n_constraint=Indata(1,4);
                    n_load=Indata(1,5);
                    type=Indata(2,1);
                    element_type=Indata(2,2);
                    
                    app.nnode.Value=n_node;
                    app.nelement.Value=n_element;
                    app.nmaterial.Value=n_material;
                    app.nconstraint.Value=n_constraint;
                    app.nload.Value=n_load;
                    app.Type1.Value=num2str(type);
                    app.Type2.Value=num2str(element_type);
                    
                    if element_type==3
                        Nnode=3;
                    else
                        Nnode=4;
                    end
                    
                    rows=2+(1:n_node);
                    coordinate=Indata(rows,1:3);
                    app.coordinate1.Data=coordinate;
                    
                    rows=2+n_node+(1:n_material);
                    material=Indata(rows,1:4);
                    app.coordinate2.Data=material;
                    
                    rows=2+n_node+n_material+(1:n_element);
                    element_nodes=Indata(rows,1:3+Nnode);
                    app.Elementnodes.Data=element_nodes;
                    
                    rows=2+n_node+n_material+n_element+(1:n_constraint);
                    constraint1=Indata(rows,1:2);
                    app.constriant.Data=constraint1;
                    
                    rows=2+n_node+n_material+n_element+n_constraint+(1:n_load);
                    load=Indata(rows,1:3);
                    app.load1.Data=load;
                    
                    value = str2num(cell2mat(app.Type1.Value));
                    if value==0
                        app.PlanStressCheckBox.Value=true;
                        app.PlanStrainCheckBox.Value=false;
                    end
                    if value==1
                        app.PlanStressCheckBox.Value=false;
                        app.PlanStrainCheckBox.Value=true;
                    end   
                         
                    value = str2num(cell2mat(app.Type2.Value));
                    if value==3
                        app.TriangularCheckBox.Value=true;
                        app.QuadrilateralCheckBox.Value=false;
                        app.MixedCheckBox.Value=false;
                    elseif value==4
                        app.TriangularCheckBox.Value=false;
                        app.QuadrilateralCheckBox.Value=true;
                        app.MixedCheckBox.Value=false;
                    elseif value==5
                        app.TriangularCheckBox.Value=false;
                        app.QuadrilateralCheckBox.Value=false;
                        app.MixedCheckBox.Value=true;
                    end      
                    
                    
        end

        % Value changed function: Type1
        function Type1ValueChanged2(app, event)
           
        end

        % Button pushed function: Plot5
        function Plot5ButtonPushed(app, event)
        global displacement
            scale=str2num(cell2mat(app.scale4.Value));
            scale22=str2num(cell2mat(app.scale5.Value));
            n_node=app.nnode.Value;
            element_type=str2num(cell2mat(app.Type2.Value));
            x=app.coordinate1.Data(:,2);
            y=app.coordinate1.Data(:,3);
            element_nodes=app.Elementnodes.Data(:,3:2+element_type);
          
            x_displacement=displacement(1:2:2*n_node-1);
            y_displacement=displacement(2:2:2*n_node);
                    
            if isempty(scale)==1
                 scale=1;
            end
             if isempty(scale22)==1
                 scale22=1;
            end
             element_type=str2num(cell2mat(app.Type2.Value));
            if element_type==3
                Nnode=3;
            else
                Nnode=4;
            end
            
            element_nodes=app.Elementnodes.Data;
            n_elementnode1=element_nodes(:,3);
            n_element=size(element_nodes,1);
            element_nodes=element_nodes(:,4:3+Nnode);
            if isempty(element_nodes)==1
                return
            else
            for i=1:n_element
                n_elementnode=n_elementnode1(i);
                for j=1:n_elementnode
                    jj=mod(j,n_elementnode)+1;
                    xx=[x(element_nodes(i,j)),x(element_nodes(i,jj))];
                    yy=[y(element_nodes(i,j)),y(element_nodes(i,jj))];
                    plot(app.Structure2,xx,yy,'b','Marker','.','LineStyle','-','LineWidth',2);
                    hold(app.Structure2, 'on')
                end
            end
            
            constrain=app.constriant.Data;
            constrain=constrain(:,2);
            if isempty(constrain)==1
                return
            else
            for i=1:size(constrain,1)
                if  mod(constrain(i),2)==0
                    px=0;py=1;po=constrain(i)/2;
                else
                    px=1;py=0;po=(constrain(i)+1)/2;
                end
                xx=[x(po)-px*0.3*scale,x(po)];
                yy=[y(po)-py*0.3*scale,y(po)];
                plot(app.Structure2,xx,yy,'r','Marker','o','LineStyle','-','LineWidth',2);
            end
            
            x_dis=0.1*scale22/max(abs(x_displacement'))*x_displacement;
            y_dis=0.1*scale22/max(abs(y_displacement'))*y_displacement;
            x2=x+x_dis;y2=y+y_dis;
            for i=1:n_element
                n_elementnode=n_elementnode1(i);
                for j=1:n_elementnode
                    jj=mod(j,n_elementnode)+1;
                    xx=[x2(element_nodes(i,j)),x2(element_nodes(i,jj))];
                    yy=[y2(element_nodes(i,j)),y2(element_nodes(i,jj))];
                    plot(app.Structure2,xx,yy,'k','Marker','.','LineStyle','--','LineWidth',1);
                end
            end

            hold(app.Structure2, 'off')
            axis(app.Structure2,[min(x)-scale max(x)+scale min(y)-scale max(y)+scale]);
            end
            end
                    
        end

        % Value changed function: MixedCheckBox
        function MixedCheckBoxValueChanged(app, event)
            value = app.MixedCheckBox.Value;
            if value==true
                app.Type2.Value='5';
                app.QuadrilateralCheckBox.Value=false;
                app.TriangularCheckBox.Value=false;
            end
            if value==false
               app.Type2.Value=' ';
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TypeTab = uitab(app.TabGroup);
            app.TypeTab.Title = 'Type';
            app.QuestiontypeLabel = uilabel(app.TypeTab);
            app.QuestiontypeLabel.Text = 'Question type:';
            app.PlanStressCheckBox = uicheckbox(app.TypeTab);
            app.PlanStressCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlanStressCheckBoxValueChanged, true);
            app.PlanStressCheckBox.Text = ' Plan Stress';
            app.PlanStrainCheckBox = uicheckbox(app.TypeTab);
            app.PlanStrainCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlanStrainCheckBoxValueChanged, true);
            app.PlanStrainCheckBox.Text = ' Plan Strain';
            app.ElementtypeLabel = uilabel(app.TypeTab);
            app.ElementtypeLabel.Text = 'Element type:';
            app.TriangularCheckBox = uicheckbox(app.TypeTab);
            app.TriangularCheckBox.ValueChangedFcn = createCallbackFcn(app, @TriangularCheckBoxValueChanged, true);
            app.TriangularCheckBox.Text = ' Triangular';
            app.QuadrilateralCheckBox = uicheckbox(app.TypeTab);
            app.QuadrilateralCheckBox.ValueChangedFcn = createCallbackFcn(app, @QuadrilateralCheckBoxValueChanged, true);
            app.QuadrilateralCheckBox.Text = ' Quadrilateral';
            app.PlaneElementCalculationLabel = uilabel(app.TypeTab);
            app.PlaneElementCalculationLabel.Text = 'Plane Element Calculation';
            app.MixedCheckBox = uicheckbox(app.TypeTab);
            app.MixedCheckBox.ValueChangedFcn = createCallbackFcn(app, @MixedCheckBoxValueChanged, true);
            app.MixedCheckBox.Text = ' Mixed';
            app.MaterialTab = uitab(app.TabGroup);
            app.MaterialTab.Title = 'Material';
            app.coordinate2 = uitable(app.MaterialTab);
            app.coordinate2.ColumnName = {'Type'; 'E'; 'u'; 't'};
            app.coordinate2.RowName = {};
            app.coordinate2.ColumnEditable = true;
            app.coordinate2.CellEditCallback = createCallbackFcn(app, @coordinate2CellEdit, true);
            app.Add2Button = uibutton(app.MaterialTab, 'push');
            app.Add2Button.ButtonPushedFcn = createCallbackFcn(app, @Add2ButtonPushed, true);
            app.Add2Button.Text = 'Add';
            app.Delete2 = uibutton(app.MaterialTab, 'push');
            app.Delete2.ButtonPushedFcn = createCallbackFcn(app, @Delete2ButtonPushed, true);
            app.Delete2.Text = 'Delete';
            app.ElasticmodulusLabel = uilabel(app.MaterialTab);
            app.ElasticmodulusLabel.Text = 'Elastic modulus';
            app.Elasticmodulus = uitextarea(app.MaterialTab);
            app.PoissonratioLabel = uilabel(app.MaterialTab);
            app.PoissonratioLabel.Text = 'Poisson ratio';
            app.Poissonratio = uitextarea(app.MaterialTab);
            app.ThicknessLabel = uilabel(app.MaterialTab);
            app.ThicknessLabel.Text = 'Thickness';
            app.Thickness = uitextarea(app.MaterialTab);
            app.CoordinateTab = uitab(app.TabGroup);
            app.CoordinateTab.Title = 'Coordinate';
            app.coordinate1 = uitable(app.CoordinateTab);
            app.coordinate1.ColumnName = {'Node'; 'X'; 'Y'};
            app.coordinate1.RowName = {};
            app.coordinate1.ColumnEditable = true;
            app.coordinate1.CellEditCallback = createCallbackFcn(app, @Statement, true);
            app.Add1Button = uibutton(app.CoordinateTab, 'push');
            app.Add1Button.ButtonPushedFcn = createCallbackFcn(app, @Add1ButtonPushed, true);
            app.Add1Button.Text = 'Add';
            app.XcoordinateLabel = uilabel(app.CoordinateTab);
            app.XcoordinateLabel.Text = 'X coordinate';
            app.Xcoordinate = uitextarea(app.CoordinateTab);
            app.YcoordinateLabel = uilabel(app.CoordinateTab);
            app.YcoordinateLabel.Text = 'Y coordinate';
            app.Ycoordinate = uitextarea(app.CoordinateTab);
            app.Plot1 = uibutton(app.CoordinateTab, 'push');
            app.Plot1.ButtonPushedFcn = createCallbackFcn(app, @Plot1ButtonPushed, true);
            app.Plot1.Text = 'Plot';
            app.Delete = uibutton(app.CoordinateTab, 'push');
            app.Delete.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.Delete.Text = 'Delete';
            app.ScaleLabel = uilabel(app.CoordinateTab);
            app.ScaleLabel.Text = 'Scale:';
            app.scale1 = uitextarea(app.CoordinateTab);
            app.ElementnodesTab = uitab(app.TabGroup);
            app.ElementnodesTab.Title = 'Element nodes';
            app.Elementnodes = uitable(app.ElementnodesTab);
            app.Elementnodes.ColumnName = {'Element'; 'Material'; 'Type'; 'Nodes'; ''; ''; ''};
            app.Elementnodes.RowName = {};
            app.Elementnodes.ColumnEditable = [true true true true false true true];
            app.Add3Button = uibutton(app.ElementnodesTab, 'push');
            app.Add3Button.ButtonPushedFcn = createCallbackFcn(app, @Add3ButtonPushed, true);
            app.Add3Button.Text = 'Add';
            app.Plot2 = uibutton(app.ElementnodesTab, 'push');
            app.Plot2.ButtonPushedFcn = createCallbackFcn(app, @Plot2ButtonPushed, true);
            app.Plot2.Text = 'Plot';
            app.Delete3 = uibutton(app.ElementnodesTab, 'push');
            app.Delete3.ButtonPushedFcn = createCallbackFcn(app, @Delete3ButtonPushed, true);
            app.Delete3.Text = 'Delete';
            app.MaterialLabel = uilabel(app.ElementnodesTab);
            app.MaterialLabel.Text = 'Material';
            app.elementmaterial = uitextarea(app.ElementnodesTab);
            app.NodesLabel = uilabel(app.ElementnodesTab);
            app.NodesLabel.Text = 'Nodes';
            app.elementnodes = uitextarea(app.ElementnodesTab);
            app.ScaleLabel_2 = uilabel(app.ElementnodesTab);
            app.ScaleLabel_2.Text = 'Scale:';
            app.scale2 = uitextarea(app.ElementnodesTab);
            app.TypeLabel = uilabel(app.ElementnodesTab);
            app.TypeLabel.Text = 'Type';
            app.elementtype2 = uitextarea(app.ElementnodesTab);
            app.ConstraintsTab = uitab(app.TabGroup);
            app.ConstraintsTab.Title = 'Constraints ';
            app.constriant = uitable(app.ConstraintsTab);
            app.constriant.ColumnName = {'Number'; 'Position'};
            app.constriant.RowName = {};
            app.constriant.ColumnEditable = true;
            app.Add4Button = uibutton(app.ConstraintsTab, 'push');
            app.Add4Button.ButtonPushedFcn = createCallbackFcn(app, @Add4ButtonPushed, true);
            app.Add4Button.Text = 'Add';
            app.Plot3 = uibutton(app.ConstraintsTab, 'push');
            app.Plot3.ButtonPushedFcn = createCallbackFcn(app, @Plot3ButtonPushed, true);
            app.Plot3.Text = 'Plot';
            app.Delete4 = uibutton(app.ConstraintsTab, 'push');
            app.Delete4.ButtonPushedFcn = createCallbackFcn(app, @Delete4ButtonPushed, true);
            app.Delete4.Text = 'Delete';
            app.PositionLabel = uilabel(app.ConstraintsTab);
            app.PositionLabel.Text = 'Position';
            app.consposition = uitextarea(app.ConstraintsTab);
            app.ScaleLabel_3 = uilabel(app.ConstraintsTab);
            app.ScaleLabel_3.Text = 'Scale:';
            app.scale3 = uitextarea(app.ConstraintsTab);
            app.LoadsTab = uitab(app.TabGroup);
            app.LoadsTab.Title = 'Loads';
            app.load1 = uitable(app.LoadsTab);
            app.load1.ColumnName = {'Number'; 'Position'; 'Value'};
            app.load1.RowName = {};
            app.load1.ColumnEditable = true;
            app.Add5 = uibutton(app.LoadsTab, 'push');
            app.Add5.ButtonPushedFcn = createCallbackFcn(app, @Add5ButtonPushed, true);
            app.Add5.Text = 'Add';
            app.Plot4 = uibutton(app.LoadsTab, 'push');
            app.Plot4.ButtonPushedFcn = createCallbackFcn(app, @Plot4ButtonPushed, true);
            app.Plot4.Text = 'Plot';
            app.Delete5 = uibutton(app.LoadsTab, 'push');
            app.Delete5.ButtonPushedFcn = createCallbackFcn(app, @Delete5ButtonPushed, true);
            app.Delete5.Text = 'Delete';
            app.PositionLabel_2 = uilabel(app.LoadsTab);
            app.PositionLabel_2.Text = 'Position';
            app.loadposition = uitextarea(app.LoadsTab);
            app.ValueLabel = uilabel(app.LoadsTab);
            app.ValueLabel.Text = 'Value';
            app.value1 = uitextarea(app.LoadsTab);
            app.ScaleLabel_4 = uilabel(app.LoadsTab);
            app.ScaleLabel_4.Text = 'Scale:';
            app.scale4 = uitextarea(app.LoadsTab);
            app.CalculateTab = uitab(app.TabGroup);
            app.CalculateTab.Title = 'Calculate';
            app.CalculateButton = uibutton(app.CalculateTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Type2 = uitextarea(app.CalculateTab);
            app.Type2.ValueChangedFcn = createCallbackFcn(app, @Type2ValueChanged, true);
            app.Type2.Editable = 'off';
            app.ElementtypeLabel_2 = uilabel(app.CalculateTab);
            app.ElementtypeLabel_2.Text = 'Element type';
            app.Type1 = uitextarea(app.CalculateTab);
            app.Type1.ValueChangedFcn = createCallbackFcn(app, @Type1ValueChanged2, true);
            app.Type1.Editable = 'off';
            app.QuestiontypeLabel_2 = uilabel(app.CalculateTab);
            app.QuestiontypeLabel_2.Text = 'Question type';
            app.nnode = uieditfield(app.CalculateTab, 'numeric');
            app.nnode.Editable = 'off';
            app.NumberofnodesLabel = uilabel(app.CalculateTab);
            app.NumberofnodesLabel.Text = 'Number of nodes:';
            app.NumberofmaterialsLabel_2 = uilabel(app.CalculateTab);
            app.NumberofmaterialsLabel_2.Text = 'Number of materials:';
            app.nmaterial = uieditfield(app.CalculateTab, 'numeric');
            app.nmaterial.Editable = 'off';
            app.NumberofelementsLabel = uilabel(app.CalculateTab);
            app.NumberofelementsLabel.Text = 'Number of elements:';
            app.nelement = uieditfield(app.CalculateTab, 'numeric');
            app.nelement.Editable = 'off';
            app.NumberofconstraintsLabel_2 = uilabel(app.CalculateTab);
            app.NumberofconstraintsLabel_2.Text = 'Number of constraints:';
            app.nconstraint = uieditfield(app.CalculateTab, 'numeric');
            app.nconstraint.Editable = 'off';
            app.NumberofloadsLabel_2 = uilabel(app.CalculateTab);
            app.NumberofloadsLabel_2.Text = 'Number of loads:';
            app.nload = uieditfield(app.CalculateTab, 'numeric');
            app.nload.Editable = 'off';
            app.SaveButton = uibutton(app.CalculateTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.OpenButton = uibutton(app.CalculateTab, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.DisplacementTab = uitab(app.TabGroup);
            app.DisplacementTab.Title = 'Displacement';
            app.Structure2 = uiaxes(app.DisplacementTab);
            app.DisplacementLabel = uilabel(app.DisplacementTab);
            app.DisplacementLabel.Text = 'Displacement';
            app.Plot5 = uibutton(app.DisplacementTab, 'push');
            app.Plot5.ButtonPushedFcn = createCallbackFcn(app, @Plot5ButtonPushed, true);
            app.Plot5.Text = 'Plot';
            app.ScaleLabel_5 = uilabel(app.DisplacementTab);
            app.ScaleLabel_5.Text = 'Scale:';
            app.scale5 = uitextarea(app.DisplacementTab);
            app.Structure = uiaxes(app.UIFigure);
            app.StructureLabel = uilabel(app.UIFigure);
            app.StructureLabel.Text = 'Structure';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Plan_Calculate

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end